classdef ProjectDSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        AmptitudeSliderLabel      matlab.ui.control.Label
        AmptitudeSlider           matlab.ui.control.Slider
        FrequencySliderLabel      matlab.ui.control.Label
        FrequencySlider           matlab.ui.control.Slider
        ShiftSliderLabel          matlab.ui.control.Label
        ShiftSlider               matlab.ui.control.Slider
        UIAxes2                   matlab.ui.control.UIAxes
        AKnobLabel                matlab.ui.control.Label
        AKnob                     matlab.ui.control.Knob
        DKnobLabel                matlab.ui.control.Label
        DKnob                     matlab.ui.control.Knob
        SKnobLabel                matlab.ui.control.Label
        SKnob                     matlab.ui.control.Knob
        RKnobLabel                matlab.ui.control.Label
        RKnob                     matlab.ui.control.Knob
        C0Button                  matlab.ui.control.Button
        D0Button                  matlab.ui.control.Button
        E0Button                  matlab.ui.control.Button
        F0Button                  matlab.ui.control.Button
        G0Button                  matlab.ui.control.Button
        A0Button                  matlab.ui.control.Button
        B0Button                  matlab.ui.control.Button
        C1Button                  matlab.ui.control.Button
        D1Button                  matlab.ui.control.Button
        E1Button                  matlab.ui.control.Button
        F1Button                  matlab.ui.control.Button
        G1Button                  matlab.ui.control.Button
        A1Button                  matlab.ui.control.Button
        B1Button                  matlab.ui.control.Button
        C2Button                  matlab.ui.control.Button
        D2Button                  matlab.ui.control.Button
        E2Button                  matlab.ui.control.Button
        F2Button                  matlab.ui.control.Button
        G2Button                  matlab.ui.control.Button
        A2Button                  matlab.ui.control.Button
        B2Button                  matlab.ui.control.Button
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        UIAxes5                   matlab.ui.control.UIAxes
        FunctionDropDownLabel     matlab.ui.control.Label
        FunctionDropDown          matlab.ui.control.DropDown
        LowPassWPassSliderLabel   matlab.ui.control.Label
        LowPassWPassSlider        matlab.ui.control.Slider
        UIAxeslow                 matlab.ui.control.UIAxes
        HighPassWPassSliderLabel  matlab.ui.control.Label
        HighPassWPassSlider       matlab.ui.control.Slider
        UIAxeshigh                matlab.ui.control.UIAxes
        PlayMIDIButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        amp=0;
        shift=0;
        freq=0;
        x = -3*pi:0.01:3*pi;
        result=0;
        A_value=1;
        D_value=1;
        S_value=1;
        R_value=1;
        wpass=0;
        func=str2func('sin');
        
         
    end


    methods (Access = public)
        
        function SoundPlay (app,value,str)
            %playing the updated function (sinocoid)
            % this function will include all the permutation to build the relevant function from inputs
         
            if (strcmp('amp',str)==1)
                sound(value*app.func(app.x))
                app.amp=value;
                app.result=value* app.func(app.x);
            end 
            if (strcmp('freq',str)==1)
                sound(app.func(app.x*value))
                app.freq=value;
                app.result= app.func(app.x*value);
            end 
            if (strcmp('shift',str)==1)
                sound(app.func(app.x+value))
                app.shift=value;
                app.result= app.func(app.x+value);
            end 
            if(app.amp~=0 && app.freq~=0 &&app.shift~=0)
             sound(app.amp* app.func((app.x*app.freq)+app.shift))
             app.result=app.amp* app.func((app.x*app.freq)+app.shift);
            end 
            if(app.amp==0 && app.freq~=0 &&app.shift~=0)
             sound( app.func((app.x*app.freq)+app.shift))
             app.result= app.func((app.x*app.freq)+app.shift);
            end 
            if(app.amp~=0 && app.freq==0 &&app.shift~=0)
             sound(app.amp* app.func((app.x)+app.shift))
             app.result=app.amp* app.func((app.x)+app.shift);
            end 
            if(app.amp~=0 && app.freq~=0 &&app.shift==0)
             sound(app.amp* app.func((app.x*app.freq)))
             app.result=app.amp* app.func((app.x*app.freq));
            end 
            UpdatePlots(app);
          % end 
        end


        % updates all the UI AXES , updated sinsoid , freq only , shift only , amp only 
        function UpdatePlots (app)
            if(app.amp~=0)
             plot(app.UIAxes,app.amp* app.func(app.x))
             app.UIAxes.YLim=[-1 1];% מגביר את הגרף 
             app.UIAxes.XLim=[0 100];
             %****changes that tomer asked from sally ! 
             plot(app.UIAxes3, app.func(app.x*app.amp))
             app.UIAxes3.YLim=[-1 1]; % מגביר את הגרף 
             app.UIAxes3.XLim=[0 100];
          % plot(app.UIAxes2,envelope(app.amp*sin(app.x),30,'analytic'))
            end 
             if(app.freq~=0)
                plot(app.UIAxes, app.func(app.x*app.freq))
                app.UIAxes.YLim=[-1 1]; 
                app.UIAxes.XLim=[0 100];
              %****changes that tomer asked from sally ! 
                plot(app.UIAxes4, app.func(app.x*app.freq))
                app.UIAxes4.YLim=[-1 1]; 
                app.UIAxes4.XLim=[0 100];
             
           %plot(app.UIAxes2,envelope(sin(app.x*app.freq),30,'analytic'))
            end 
             if(app.shift~=0)
                plot(app.UIAxes, app.func(app.x+app.shift))
            app.UIAxes.YLim=[-1 1]; 
             app.UIAxes.XLim=[0 100];
             
             %****changes that tomer asked from sally ! 
             plot(app.UIAxes5, app.func(app.x*app.shift))
                app.UIAxes5.YLim=[-1 1]; 
                app.UIAxes5.XLim=[0 100];
             
           %plot(app.UIAxes2,envelope(sin(app.x+app.shift),30,'analytic'))
             end 
             if(app.amp~=0 && app.freq~=0 && app.shift~=0)
                  plot(app.UIAxes,app.amp* app.func((app.x* app.freq)+app.shift))
            app.UIAxes.YLim=[-1 1]; 
             app.UIAxes.XLim=[0 100];
           %plot(app.UIAxes2,envelope(app.amp*sin((app.x* app.freq)+app.shift),30,'analytic'))
             end 
         if(app.amp~=0 && app.shift~=0 && app.freq==0)
                 plot(app.UIAxes,app.amp* app.func((app.x)+app.shift))
            app.UIAxes.YLim=[-1 1]; 
             app.UIAxes.XLim=[0 100];
           %plot(app.UIAxes2,envelope(app.amp*sin((app.x)+app.shift),30,'analytic'))
         end 
          if(app.amp==0 && app.shift~=0 && app.freq~=0)
                 plot(app.UIAxes, app.func((app.x*app.freq)+app.shift))
            app.UIAxes.YLim=[-1 1]; 
             app.UIAxes.XLim=[0 100];
          % plot(app.UIAxes2,envelope(sin((app.x*app.freq)+app.shift),30,'analytic'))
          end 
                    if(app.amp~=0 && app.shift==0 && app.freq~=0)
                        plot(app.UIAxes,app.amp* app.func((app.x* app.freq)))
            app.UIAxes.YLim=[-1 1]; 
             app.UIAxes.XLim=[0 100];
          % plot(app.UIAxes2,envelope(app.amp*sin((app.x* app.freq)),30,'analytic'))
                    end 
        end


        function UpdateADSR(app)
           
            ADSR=[app.A_value app.D_value app.S_value app.R_value];
             plot(app.UIAxes2,ADSR); 
        end
    end

    methods (Access = private)


    end
  

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                       app.FunctionDropDown.Items={'Sin','Square','Saw'};
                        
          %  app.UIFigure.Children=app.UIAxes;
%             o=imread('dsp.jpg');
%              imshow(o,'parent',app.UIAxes);
        end

        % Value changed function: AmptitudeSlider
        function AmptitudeSliderValueChanged(app, event)
            value = app.AmptitudeSlider.Value;
            str='amp';
            if value~=0
               SoundPlay(app,value,str);
        else 
            app.amp=0;
        end 
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            str='freq';
            if value~=0
               SoundPlay(app,value,str);
            else 
                app.freq=0;
            end 
            
            
        end

        % Value changed function: ShiftSlider
        function ShiftSliderValueChanged(app, event)

            value = app.ShiftSlider.Value;
            str='shift';
            if value~=0
               SoundPlay(app,value,str);
            else 
                app.shift=0;
            end 

        end

        % Value changed function: AKnob
        function AKnobValueChanged(app, event)
            
           value = app.AKnob.Value;
            len=length(app.x);
            app.A_value=len*value*app.x*0.1; % attack is basiclly 10% of the total length of a signal
             UpdateADSR(app);
            
       
%              plot(app.UIAxes2,envelope(newsignal,30,'analytic'))
%              app.UIAxes2.XLim=[0 100];
%              app.A_value=value;


        end

        % Value changed function: DKnob
        function DKnobValueChanged(app, event)
            value = app.DKnob.Value;
            len=length(app.x);
             app.D_value=len*value*app.x*0.15; % D is basiclly 15% of the total length of a signal
           % newsignal=len*value*app.result*0.2; % D is basiclly 15% of the total length of a signal
%              plot(app.UIAxes2,envelope(newsignal,30,'analytic'))
%              app.UIAxes2.XLim=[0 100];
           UpdateADSR(app);


        end

        % Value changed function: SKnob
        function SKnobValueChanged(app, event)
            value = app.SKnob.Value;
            len=length(app.x);
            app.S_value=len*value*app.x*0.5; % S is basiclly 60% of the total length of a signal
%             %newsignal=len*value*app.result*0.6; % S is basiclly 60% of the total length of a signal
%              plot(app.UIAxes2,envelope(newsignal,30,'analytic'))
%              
%              app.UIAxes2.XLim=[0 100];
            UpdateADSR(app);
        end

        % Value changed function: RKnob
        function RKnobValueChanged(app, event)
            value = app.RKnob.Value;
            len=length(app.x);
         app.R_value=len*value*app.x*0.1; % R is basiclly 15% of the total length of a signal
      UpdateADSR(app);
        end

        % Button pushed function: C0Button
        function C0ButtonPushed(app, event)
            app.freq=16.35;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: D0Button
        function D0ButtonPushed(app, event)
            app.freq=18.35;
              SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: E0Button
        function E0ButtonPushed(app, event)
            app.freq= 20.60;
              SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: F0Button
        function F0ButtonPushed(app, event)
            app.freq=21.83;
             SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: G0Button
        function G0ButtonPushed(app, event)
            app.freq=24.50;
             SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: A0Button
        function A0ButtonPushed(app, event)
            app.freq=27.50;
           SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: B0Button
        function B0ButtonPushed(app, event)
            app.freq=30.87;
               SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function D4Button_2Pushed(app, event)
            app.freq=293.66;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function E4Button_2Pushed(app, event)
            app.freq=329.63;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function F4Button_2Pushed(app, event)
            app.freq=349.23;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function G4Button_3Pushed(app, event)
            app.freq=392;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function A4Button_2Pushed(app, event)
            app.freq=440;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function B4Button_2Pushed(app, event)
            app.freq= 493.88;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function C5Button_2Pushed(app, event)
            app.freq= 523.25;
             SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function D5Button_2Pushed(app, event)
            app.freq= 587.33;
             SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function E5Button_2Pushed(app, event)
            app.freq= 659.26;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function G5Button_2Pushed(app, event)
            app.freq= 783.99;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function A5Button_2Pushed(app, event)
            app.freq=880;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function B5Button_2Pushed(app, event)
            app.freq=988;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: C1Button
        function C1ButtonPushed(app, event)
             app.freq=32.7;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: D1Button
        function D1ButtonPushed(app, event)
             app.freq=36.71;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: E1Button
        function E1ButtonPushed(app, event)
             app.freq=41.20;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: F1Button
        function F1ButtonPushed(app, event)
             app.freq=43.65;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: G1Button
        function G1ButtonPushed(app, event)
             app.freq=49;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: A1Button
        function A1ButtonPushed(app, event)
             app.freq=55;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: B1Button
        function B1ButtonPushed(app, event)
             app.freq=61.74;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: C2Button
        function C2ButtonPushed(app, event)
             app.freq=65.41;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: D2Button
        function D2ButtonPushed(app, event)
             app.freq=73.41;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: E2Button
        function E2ButtonPushed(app, event)
             app.freq=82.41;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: F2Button
        function F2ButtonPushed(app, event)
             app.freq=87.31;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: G2Button
        function G2ButtonPushed(app, event)
               app.freq=98;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: A2Button
        function A2ButtonPushed(app, event)
               app.freq=110;
            SoundPlay(app,app.freq,'freq');
        end

        % Button pushed function: B2Button
        function B2ButtonPushed(app, event)
               app.freq=123.47;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function C3ButtonPushed(app, event)
               app.freq=130.81;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function D3ButtonPushed(app, event)
               app.freq=146.83;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function E3ButtonPushed(app, event)
               app.freq=164.81;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function F3ButtonPushed(app, event)
               app.freq=174.61;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function G3ButtonPushed(app, event)
               app.freq=196;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function A3ButtonPushed(app, event)
               app.freq=220;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function B3ButtonPushed(app, event)
               app.freq=246.94;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function C4ButtonPushed(app, event)
               app.freq=261.63;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function D4ButtonPushed(app, event)
               app.freq=293.66;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function E4ButtonPushed(app, event)
               app.freq=329.63;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function F4ButtonPushed(app, event)
               app.freq=349.23;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function G4ButtonPushed(app, event)
               app.freq=392;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function A4ButtonPushed(app, event)
               app.freq=440;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function B4ButtonPushed(app, event)
               app.freq=493.88;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function C5ButtonPushed(app, event)
               app.freq=523.25;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function D5ButtonPushed(app, event)
               app.freq=587.33;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function E5ButtonPushed(app, event)
               app.freq=659.25;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function F5ButtonPushed(app, event)
               app.freq=698.46;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function G5ButtonPushed(app, event)
               app.freq=783.99;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function A5ButtonPushed(app, event)
               app.freq=880;
            SoundPlay(app,app.freq,'freq');
        end

        % Callback function
        function B5ButtonPushed(app, event)
               app.freq=987.77;
            SoundPlay(app,app.freq,'freq');
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            % clearing plots when the function changes 
            cla(app.UIAxeslow);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            cla(app.UIAxes4);
            cla(app.UIAxes5);
            cla(app.UIAxeshigh);
            cla(app.UIAxes);
            value = app.FunctionDropDown.Value;
            %disp(value);
             if strcmp(value,'Sin')
            app.func = str2func('sin'); 
             end 
            if strcmp(value,'Saw')
           app.func=str2func('sawtooth');
           
            end 
              if strcmp(value,'Square')
               app.func=str2func('square');
            end 
                
        end

        % Callback function
        function LowPassSwitchValueChanged(app, event)

             
        end

        % Value changed function: LowPassWPassSlider
        function LowPassWPassSliderValueChanged(app, event)
            value = app.LowPassWPassSlider.Value;
            app.wpass=value; % wpass [0 1] only -  Normalized passband frequency
             if(value~=0 )
              y=lowpass(app.result,app.wpass); 
              sound(y);    
              plot(app.UIAxeslow,app.x,app.result,app.x,y);
              app.UIAxeslow.XLim=[0 1];

             end 
             
             
        end

        % Value changed function: HighPassWPassSlider
        function HighPassWPassSliderValueChanged(app, event)
            value = app.HighPassWPassSlider.Value;
             % wpass [0 1] only - Normalized passband frequency
             if(value~=0 )
              y=highpass(app.result,value); 
              sound(y);
              plot(app.UIAxeshigh,app.x,app.result,app.x,y);
              app.UIAxeshigh.XLim=[0 1];
            end 
        end

        % Button pushed function: PlayMIDIButton
        function PlayMIDIButtonPushed(app, event)
%             note_matrix = readmidi_java('audio.midi'); 
%             disp(note_matrix)
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sin Function')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmptitudeSliderLabel = uilabel(app.UIFigure);
            app.AmptitudeSliderLabel.Text = 'Amptitude ';
            app.AmptitudeSlider = uislider(app.UIFigure);
            app.AmptitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmptitudeSliderValueChanged, true);
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.ShiftSliderLabel = uilabel(app.UIFigure);
            app.ShiftSliderLabel.Text = 'Shift';
            app.ShiftSlider = uislider(app.UIFigure);
            app.ShiftSlider.ValueChangedFcn = createCallbackFcn(app, @ShiftSliderValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Envelope')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.AKnobLabel = uilabel(app.UIFigure);
            app.AKnobLabel.Text = 'A';
            app.AKnob = uiknob(app.UIFigure, 'continuous');
            app.AKnob.Limits = [0 10];
            app.AKnob.ValueChangedFcn = createCallbackFcn(app, @AKnobValueChanged, true);
            app.DKnobLabel = uilabel(app.UIFigure);
            app.DKnobLabel.Text = 'D';
            app.DKnob = uiknob(app.UIFigure, 'continuous');
            app.DKnob.Limits = [0 10];
            app.DKnob.ValueChangedFcn = createCallbackFcn(app, @DKnobValueChanged, true);
            app.SKnobLabel = uilabel(app.UIFigure);
            app.SKnobLabel.Text = 'S';
            app.SKnob = uiknob(app.UIFigure, 'continuous');
            app.SKnob.Limits = [0 10];
            app.SKnob.ValueChangedFcn = createCallbackFcn(app, @SKnobValueChanged, true);
            app.RKnobLabel = uilabel(app.UIFigure);
            app.RKnobLabel.Text = 'R';
            app.RKnob = uiknob(app.UIFigure, 'continuous');
            app.RKnob.Limits = [0 10];
            app.RKnob.ValueChangedFcn = createCallbackFcn(app, @RKnobValueChanged, true);
            app.C0Button = uibutton(app.UIFigure, 'push');
            app.C0Button.ButtonPushedFcn = createCallbackFcn(app, @C0ButtonPushed, true);
            app.C0Button.Text = 'C0';
            app.D0Button = uibutton(app.UIFigure, 'push');
            app.D0Button.ButtonPushedFcn = createCallbackFcn(app, @D0ButtonPushed, true);
            app.D0Button.Text = 'D0';
            app.E0Button = uibutton(app.UIFigure, 'push');
            app.E0Button.ButtonPushedFcn = createCallbackFcn(app, @E0ButtonPushed, true);
            app.E0Button.Text = 'E0';
            app.F0Button = uibutton(app.UIFigure, 'push');
            app.F0Button.ButtonPushedFcn = createCallbackFcn(app, @F0ButtonPushed, true);
            app.F0Button.Text = 'F0';
            app.G0Button = uibutton(app.UIFigure, 'push');
            app.G0Button.ButtonPushedFcn = createCallbackFcn(app, @G0ButtonPushed, true);
            app.G0Button.Text = 'G0';
            app.A0Button = uibutton(app.UIFigure, 'push');
            app.A0Button.ButtonPushedFcn = createCallbackFcn(app, @A0ButtonPushed, true);
            app.A0Button.Text = 'A0';
            app.B0Button = uibutton(app.UIFigure, 'push');
            app.B0Button.ButtonPushedFcn = createCallbackFcn(app, @B0ButtonPushed, true);
            app.B0Button.Text = 'B0';
            app.C1Button = uibutton(app.UIFigure, 'push');
            app.C1Button.ButtonPushedFcn = createCallbackFcn(app, @C1ButtonPushed, true);
            app.C1Button.Text = 'C1';
            app.D1Button = uibutton(app.UIFigure, 'push');
            app.D1Button.ButtonPushedFcn = createCallbackFcn(app, @D1ButtonPushed, true);
            app.D1Button.Text = 'D1';
            app.E1Button = uibutton(app.UIFigure, 'push');
            app.E1Button.ButtonPushedFcn = createCallbackFcn(app, @E1ButtonPushed, true);
            app.E1Button.Text = 'E1';
            app.F1Button = uibutton(app.UIFigure, 'push');
            app.F1Button.ButtonPushedFcn = createCallbackFcn(app, @F1ButtonPushed, true);
            app.F1Button.Text = 'F1';
            app.G1Button = uibutton(app.UIFigure, 'push');
            app.G1Button.ButtonPushedFcn = createCallbackFcn(app, @G1ButtonPushed, true);
            app.G1Button.Text = 'G1';
            app.A1Button = uibutton(app.UIFigure, 'push');
            app.A1Button.ButtonPushedFcn = createCallbackFcn(app, @A1ButtonPushed, true);
            app.A1Button.Text = 'A1';
            app.B1Button = uibutton(app.UIFigure, 'push');
            app.B1Button.ButtonPushedFcn = createCallbackFcn(app, @B1ButtonPushed, true);
            app.B1Button.Text = 'B1';
            app.C2Button = uibutton(app.UIFigure, 'push');
            app.C2Button.ButtonPushedFcn = createCallbackFcn(app, @C2ButtonPushed, true);
            app.C2Button.Text = 'C2';
            app.D2Button = uibutton(app.UIFigure, 'push');
            app.D2Button.ButtonPushedFcn = createCallbackFcn(app, @D2ButtonPushed, true);
            app.D2Button.Text = 'D2';
            app.E2Button = uibutton(app.UIFigure, 'push');
            app.E2Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.E2Button.Text = 'E2';
            app.F2Button = uibutton(app.UIFigure, 'push');
            app.F2Button.ButtonPushedFcn = createCallbackFcn(app, @F2ButtonPushed, true);
            app.F2Button.Text = 'F2';
            app.G2Button = uibutton(app.UIFigure, 'push');
            app.G2Button.ButtonPushedFcn = createCallbackFcn(app, @G2ButtonPushed, true);
            app.G2Button.Text = 'G2';
            app.A2Button = uibutton(app.UIFigure, 'push');
            app.A2Button.ButtonPushedFcn = createCallbackFcn(app, @A2ButtonPushed, true);
            app.A2Button.Text = 'A2';
            app.B2Button = uibutton(app.UIFigure, 'push');
            app.B2Button.ButtonPushedFcn = createCallbackFcn(app, @B2ButtonPushed, true);
            app.B2Button.Text = 'B2';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Amplitude only')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Frequency only')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Shift only')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function:';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.LowPassWPassSliderLabel = uilabel(app.UIFigure);
            app.LowPassWPassSliderLabel.Text = 'LowPass : WPass';
            app.LowPassWPassSlider = uislider(app.UIFigure);
            app.LowPassWPassSlider.Limits = [0 1];
            app.LowPassWPassSlider.ValueChangedFcn = createCallbackFcn(app, @LowPassWPassSliderValueChanged, true);
            app.UIAxeslow = uiaxes(app.UIFigure);
            title(app.UIAxeslow, 'Lowpass')
            xlabel(app.UIAxeslow, 'X')
            ylabel(app.UIAxeslow, 'Y')
            app.HighPassWPassSliderLabel = uilabel(app.UIFigure);
            app.HighPassWPassSliderLabel.Text = 'HighPass : WPass';
            app.HighPassWPassSlider = uislider(app.UIFigure);
            app.HighPassWPassSlider.Limits = [0 1];
            app.HighPassWPassSlider.ValueChangedFcn = createCallbackFcn(app, @HighPassWPassSliderValueChanged, true);
            app.HighPassWPassSlider.Value = 1;
            app.UIAxeshigh = uiaxes(app.UIFigure);
            title(app.UIAxeshigh, 'Highpass')
            xlabel(app.UIAxeshigh, 'X')
            ylabel(app.UIAxeshigh, 'Y')
            app.PlayMIDIButton = uibutton(app.UIFigure, 'push');
            app.PlayMIDIButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMIDIButtonPushed, true);
            app.PlayMIDIButton.Text = 'Play MIDI ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProjectDSP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end